classdef hydrogen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HyProversionUIFigure            matlab.ui.Figure
        H2RangeLabel                    matlab.ui.control.Label
        pHValue                         matlab.ui.control.NumericEditField
        MaxInput                        matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MinInput                        matlab.ui.control.NumericEditField
        MinEditFieldLabel               matlab.ui.control.Label
        ShowPointCheckBox               matlab.ui.control.CheckBox
        ShowTrendCheckBox               matlab.ui.control.CheckBox
        SelectXaxisDropDown             matlab.ui.control.DropDown
        SelectXaxisDropDownLabel        matlab.ui.control.Label
        mumolLabel                      matlab.ui.control.Label
        Hydrogenvalue                   matlab.ui.control.NumericEditField
        HydrogenvalueEditFieldLabel     matlab.ui.control.Label
        PredictedHydrogenProductionLabel  matlab.ui.control.Label
        BET                             matlab.ui.control.NumericEditField
        BETSurfaceAream2gLabel          matlab.ui.control.Label
        IrradiationTime                 matlab.ui.control.NumericEditField
        pH                              matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        ExportResultButton              matlab.ui.control.Button
        version10Label                  matlab.ui.control.Label
        ScancharacteristicLabel         matlab.ui.control.Label
        ClearButton                     matlab.ui.control.Button
        Amountofmethanol                matlab.ui.control.NumericEditField
        AbsolutepotentialwindowVLabel   matlab.ui.control.Label
        Amountofphotocatalyst           matlab.ui.control.NumericEditField
        ElectrolyteconcentrationmLabel  matlab.ui.control.Label
        PtLoading                       matlab.ui.control.NumericEditField
        ScanratemVsLabel                matlab.ui.control.Label
        Inputparameters                 matlab.ui.control.Label
        PredictButton                   matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Property % Description
        TrainedModelData; 
        ModelLoaded = false;
    end
         

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.HyProversionUIFigure.Name = 'HyPro (version 1.0)';
            modelFileName = 'SelectedModels_h2_25.mat';
            if isfile(modelFileName)
                try
                    app.TrainedModelData = load(modelFileName);
                    app.ModelLoaded = true;
                catch
                    uialert(app.HyProversionUIFigure, 'Error loading model file.', 'Load Error');
                end
            else
                
                uialert(app.HyProversionUIFigure, ...
                    ['File "', modelFileName, '" not found. Please run predictHydrogen.m first.'], ...
                    'File Missing');
            end 
        end

        % Callback function: PredictButton, UIAxes
        function GenerateCVButtonPushed(app, event)
            data = app.TrainedModelData;
            val_Pt      = app.PtLoading.Value;
            val_Photocat = app.Amountofphotocatalyst.Value;
            val_Meth    = app.Amountofmethanol.Value;
            val_pH      = app.pHValue.Value; 
            val_Time    = app.IrradiationTime.Value; 
            val_BET     = app.BET.Value; 

            single_input = [val_Pt; val_Photocat; val_Meth; val_pH; val_Time; val_BET];

            out_mu = 2776.331;
            out_sigma = 5179.16;


            % Normalization
            mu = data.inputdata_mean';  
            sigma = data.inputdata_std';
            input_norm = (single_input - mu) ./ sigma;

            % Average Models
            cases_to_use = [
                struct('h', 17, 'i', 2);
                struct('h', 18, 'i', 1);
                struct('h', 19, 'i', 1);
                struct('h', 46, 'i', 2);
                struct('h', 49, 'i', 2);
            ];

            num_models = length(cases_to_use);

%% --- [ส่วนที่ 1: การคำนวณค่า Hydrogen Value (ตัวเลข)] ---
            % แก้ไข: คำนวณแบบ Time-Sweep (เช็คตั้งแต่นาทีที่ 0 ถึงปัจจุบัน)
            % เพื่อป้องกันค่าลดลงเมื่อเวลาเพิ่มขึ้น
            
            if val_Time > 0
                % สร้างจุดจำลองเวลา 20 จุด จาก 0 ถึง เวลาที่กรอก
                time_steps = linspace(0, val_Time, 20); 
                
                % สร้าง Matrix ข้อมูลที่ Input อื่นคงที่ แต่เวลาเปลี่ยนไปเรื่อยๆ
                check_input = [
                    repmat(val_Pt, 1, 20);
                    repmat(val_Photocat, 1, 20);
                    repmat(val_Meth, 1, 20);
                    repmat(val_pH, 1, 20);
                    time_steps;  % <--- เวลาเปลี่ยนจาก 0 -> val_Time
                    repmat(val_BET, 1, 20)
                ];
                
                % ทำนายค่าทั้งหมด 20 จุด
                input_norm_check = (check_input - mu) ./ sigma;
                acc_check = zeros(1, 20);
                for k = 1:num_models
                    fname_fold = sprintf('Fold%d', cases_to_use(k).i);
                    fname_node = sprintf('Node%d', cases_to_use(k).h);
                    net = data.selected_models.(fname_node).(fname_fold).net;
                    acc_check = acc_check + net(input_norm_check);
                end
                
                raw_predictions = ((acc_check / num_models) * out_sigma) + out_mu;
                
                % *หัวใจสำคัญ*: ใช้ค่าสูงสุดที่เคยทำได้ (Cumulative Max)
                % ถ้า AI ทำนายว่านาทีที่ 200 ได้น้อยกว่านาทีที่ 100 -> ให้ใช้ค่าของนาทีที่ 100
                predicted_H2 = max(raw_predictions);
            else
                predicted_H2 = 0;
            end
            
            % บังคับเป็น 0 ถ้าสารตั้งต้นตัวอื่นเป็น 0
            if val_Pt <= 0 || val_Photocat <= 0 || val_Time <= 0 || val_BET <= 0 
                predicted_H2 = 0;
            end
            
            % อัปเดตตัวเลขบนหน้าจอทันที
            app.Hydrogenvalue.Value = predicted_H2;

            low_val = predicted_H2 * 0.85;  % ลบ 15%
            high_val = predicted_H2 * 1.15; % บวก 15%
            
            % สั่งให้แสดงผลใน Label ที่เราเพิ่งลากมาวาง
            app.H2RangeLabel.Text = sprintf('(Range of prediction is %.2f to %.2f µmol)', low_val, high_val);

            %% --- [ส่วนที่ 2: การสร้างกราฟ (Trend Line)] ---
            selected_X = app.SelectXaxisDropDown.Value;
            nPoints = 100;
            user_min = app.MinInput.Value;
            user_max = app.MaxInput.Value;
            if user_min >= user_max, user_max = user_min + 1; end
            
            x_range = linspace(user_min, user_max, nPoints);
            
            % สร้าง Base Vector
            vec_Pt   = repmat(val_Pt, 1, nPoints);
            vec_Photocat  = repmat(val_Photocat, 1, nPoints);
            vec_Meth = repmat(val_Meth, 1, nPoints);
            vec_pH   = repmat(val_pH, 1, nPoints);
            vec_Time = repmat(val_Time, 1, nPoints);
            vec_BET  = repmat(val_BET, 1, nPoints);
            
            x_label_txt = '';
            current_X_point = 0;
        
            switch selected_X
                case '%Pt'
                    vec_Pt = x_range; x_label_txt = '%Pt Loading (wt%)'; current_X_point = val_Pt;
                case 'Photocatalyst'
                    vec_Photocat = x_range; x_label_txt = 'Photocatalyst Amount (g)'; current_X_point = val_Photocat;
                case 'Methanol'
                    vec_Meth = x_range; x_label_txt = 'Methanol Amount (mL)'; current_X_point = val_Meth;
                case 'pH'
                    vec_pH = x_range; x_label_txt = 'pH Value'; current_X_point = val_pH;
                case 'Irradiation time'
                    vec_Time = x_range; x_label_txt = 'Irradiation Time (min)'; current_X_point = val_Time;
                case 'BET'
                    vec_BET = x_range; x_label_txt = 'BET Surface Area (m^2/g)'; current_X_point = val_BET;
            end
            
            batch_input = [vec_Pt; vec_Photocat; vec_Meth; vec_pH; vec_Time; vec_BET];
            input_norm_batch = (batch_input - mu) ./ sigma;
            accumulated_batch = zeros(1, size(batch_input, 2));
            
            for k = 1:num_models
                fname_fold = sprintf('Fold%d', cases_to_use(k).i);
                fname_node = sprintf('Node%d', cases_to_use(k).h);
                net = data.selected_models.(fname_node).(fname_fold).net;
                accumulated_batch = accumulated_batch + net(input_norm_batch);
            end
            
            y_trend = ((accumulated_batch / num_models) * out_sigma) + out_mu;

            %% --- [ส่วนที่ 3: การปรับแต่งผลลัพธ์กราฟ (Constraint)] ---
            
            % กฎข้อที่ 1: ถ้าปัจจัยหลักเป็น 0 กราฟต้องเป็น 0
            if (~strcmp(selected_X, '%Pt') && val_Pt <= 0) || ...
               (~strcmp(selected_X, 'Photocatalyst') && val_Photocat <= 0) || ...
               (~strcmp(selected_X, 'Irradiation time') && val_Time <= 0) || ...
               (~strcmp(selected_X, 'BET') && val_BET <= 0)
                y_trend = zeros(size(y_trend));
                predicted_H2 = 0; % อัปเดตตัวเลขให้เป็น 0 ตามด้วย
                app.Hydrogenvalue.Value = 0;
            end
            
            % กฎข้อที่ 2: เฉพาะแกน Irradiation Time ต้องห้ามลดลง
            if strcmp(selected_X, 'Irradiation time')
                % 1. บังคับจุดเริ่มเป็น 0
                [min_val, min_idx] = min(x_range);
                if min_val <= 1e-5, y_trend(min_idx) = 0; end
                
                % 2. บังคับเส้นกราฟห้ามดิ่งลง
                for m = 2:length(y_trend)
                    if y_trend(m) < y_trend(m-1)
                        y_trend(m) = y_trend(m-1); 
                    end
                end
                
                % 3. *สำคัญ* ดึงค่าจากเส้นกราฟที่แก้แล้วมาใส่ตัวเลข (เพื่อให้กราฟกับตัวเลขตรงกัน 100%)
                [~, current_idx] = min(abs(x_range - val_Time));
                predicted_H2 = y_trend(current_idx);
                app.Hydrogenvalue.Value = predicted_H2;
            end
            
            %% --- [ส่วนที่ 4: Plotting] ---
            cla(app.UIAxes);
            reset(app.UIAxes);
            hold(app.UIAxes, 'on');
        
            if app.ShowTrendCheckBox.Value
                plot(app.UIAxes, x_range, y_trend, 'LineWidth', 2, 'DisplayName', 'Trend Line');
            end
            
            if app.ShowPointCheckBox.Value
                plot(app.UIAxes, current_X_point, predicted_H2, 'ro', ...
                    'MarkerSize', 8, 'MarkerFaceColor', 'r', 'DisplayName', 'Current Point');
            end
            
            title(app.UIAxes, ['Effect of ', selected_X, ' on H_2']);
            xlabel(app.UIAxes, x_label_txt);
            ylabel(app.UIAxes, 'H_2 Production (\mumol)');
            grid(app.UIAxes, 'on');
            legend(app.UIAxes, 'Location', 'best');
            hold(app.UIAxes, 'off');
       
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes);
            hold(app.UIAxes,'off');
            
            app.Hydrogenvalue.Value = 0;
            app.PtLoading.Value=0;
            app.Amountofmethanol.Value=0;
            app.Amountofphotocatalyst.Value=0;
            app.IrradiationTime.Value=0;
            app.BET.Value=0;
            app.pHValue.Value = 7;
            % app.Switch.Value = '';
            % app.HoldstatusLamp.Color = [0.87 0.05 0.05];
        end

        % Callback function
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if isequal(value,'Hold')
                hold(app.UIAxes,'on');
                app.HoldstatusLamp.Color=[0.07 0.81 0.07];
            else
                hold(app.UIAxes,'off');
                app.HoldstatusLamp.Color=[0.87 0.05 0.05];
            end
        end

        % Button pushed function: ExportResultButton
        function ExportResultButtonPushed(app, event)
            Pt_Val   = app.PtLoading.Value;
            Cat_Val  = app.Amountofphotocatalyst.Value;
            Meth_Val = app.Amountofmethanol.Value;
            pH_Val   = app.pHValue.Value;
            Time_Val = app.IrradiationTime.Value;
            BET_Val  = app.BET.Value;
            H2_Result = app.Hydrogenvalue.Value;

            T = table(Pt_Val, Cat_Val, Meth_Val, pH_Val, Time_Val, BET_Val, H2_Result, ...
                'VariableNames', {'Pt_Loading_wt', 'Photocatalyst_g', 'Methanol_mL', ...
                                  'pH', 'IrradiationTime_min', 'BET_m2/g', 'Predicted_H2_micromoles'});

            % 3. where save (Save As)
            [file, path] = uiputfile('*.xlsx', 'Save Prediction Result');
            
            % if cancle the window will be stop
            if isequal(file, 0)
                return;
            end
            
            % 4. save
            filename = fullfile(path, file);
            try
                writetable(T, filename);
                uialert(app.HyProversionUIFigure, 'Export Data Successfully!', 'Success');
            catch ME
                uialert(app.HyProversionUIFigure, ['Export Failed: ' ME.message], 'Error');
            end
        end

        % Value changed function: SelectXaxisDropDown
        function SelectXaxisDropDownValueChanged(app, event)
            switch app.SelectXaxisDropDown.Value
                case '%Pt'
                    app.MinInput.Value = 0; 
                    app.MaxInput.Value = 5;
                case 'Photocatalyst'
                    app.MinInput.Value = 0; 
                    app.MaxInput.Value = 3;
                case 'Methanol'
                    app.MinInput.Value = 0; 
                    app.MaxInput.Value = 200;
                case 'pH'
                    app.MinInput.Value = 1; 
                    app.MaxInput.Value = 14;
                case 'Irradiation time'
                    app.MinInput.Value = 0; 
                    app.MaxInput.Value = 500;
                case 'BET'
                    app.MinInput.Value = 40; 
                    app.MaxInput.Value = 400;
            end

            GenerateCVButtonPushed(app, event);
            
        end

        % Value changed function: ShowTrendCheckBox
        function ShowTrendCheckBoxValueChanged(app, event)
            GenerateCVButtonPushed(app, event);
            
        end

        % Value changed function: ShowPointCheckBox
        function ShowPointCheckBoxValueChanged(app, event)
            GenerateCVButtonPushed(app, event);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.HyProversionUIFigure = uifigure('Visible', 'off');
            app.HyProversionUIFigure.Name = 'Crypton (Beta version)';
            app.HyProversionUIFigure.Icon = fullfile(pathToMLAPP, 'Logo_0', 'Crypton2-1.png');
            app.UIAxes = uiaxes(app.HyProversionUIFigure);
            title(app.UIAxes, 'Prediction Result Analysis')
            ylabel(app.UIAxes, 'Hydrogen (\mumol)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @GenerateCVButtonPushed, true);
            app.PredictButton = uibutton(app.HyProversionUIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateCVButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.Inputparameters = uilabel(app.HyProversionUIFigure);
            app.Inputparameters.Text = ' Input parameters ';
            app.ScanratemVsLabel = uilabel(app.HyProversionUIFigure);
            app.ScanratemVsLabel.Interpreter = 'tex';
            app.ScanratemVsLabel.Text = '%Pt Loading (wt%)';
            app.PtLoading = uieditfield(app.HyProversionUIFigure, 'numeric');
            app.PtLoading.Limits = [0 Inf];
            app.ElectrolyteconcentrationmLabel = uilabel(app.HyProversionUIFigure);
            app.ElectrolyteconcentrationmLabel.Interpreter = 'tex';
            app.ElectrolyteconcentrationmLabel.Text = 'Amount of photocatalyst (g)';
            app.Amountofphotocatalyst = uieditfield(app.HyProversionUIFigure, 'numeric');
            app.Amountofphotocatalyst.Limits = [0 Inf];
            app.AbsolutepotentialwindowVLabel = uilabel(app.HyProversionUIFigure);
            app.AbsolutepotentialwindowVLabel.Interpreter = 'tex';
            app.AbsolutepotentialwindowVLabel.Text = 'Amount of methanol (mL)';
            app.Amountofmethanol = uieditfield(app.HyProversionUIFigure, 'numeric');
            app.Amountofmethanol.Limits = [0 Inf];
            app.ClearButton = uibutton(app.HyProversionUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ScancharacteristicLabel = uilabel(app.HyProversionUIFigure);
            app.ScancharacteristicLabel.Interpreter = 'tex';
            app.ScancharacteristicLabel.Text = 'Irradiation time (min)';
            app.version10Label = uilabel(app.HyProversionUIFigure);
            app.version10Label.Text = 'version 1.0';
            app.ExportResultButton = uibutton(app.HyProversionUIFigure, 'push');
            app.ExportResultButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResultButtonPushed, true);
            app.ExportResultButton.Text = 'Export Result';
            app.Image = uiimage(app.HyProversionUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo_0', 'Crypton2-2.png');
            app.pH = uilabel(app.HyProversionUIFigure);
            app.pH.Interpreter = 'tex';
            app.pH.Text = 'pH for Hydrogen testing';
            app.IrradiationTime = uieditfield(app.HyProversionUIFigure, 'numeric');
            app.IrradiationTime.Limits = [0 Inf];
            app.BETSurfaceAream2gLabel = uilabel(app.HyProversionUIFigure);
            app.BETSurfaceAream2gLabel.Interpreter = 'tex';
            app.BETSurfaceAream2gLabel.Text = 'BET Surface Area (m^2/g)';
            app.BET = uieditfield(app.HyProversionUIFigure, 'numeric');
            app.BET.Limits = [0 Inf];
            app.PredictedHydrogenProductionLabel = uilabel(app.HyProversionUIFigure);
            app.PredictedHydrogenProductionLabel.Text = 'Predicted Hydrogen Production';
            app.HydrogenvalueEditFieldLabel = uilabel(app.HyProversionUIFigure);
            app.HydrogenvalueEditFieldLabel.Text = 'Hydrogen value';
            app.Hydrogenvalue = uieditfield(app.HyProversionUIFigure, 'numeric');
            app.mumolLabel = uilabel(app.HyProversionUIFigure);
            app.mumolLabel.Interpreter = 'tex';
            app.mumolLabel.Text = '\mumol';
            app.SelectXaxisDropDownLabel = uilabel(app.HyProversionUIFigure);
            app.SelectXaxisDropDownLabel.Text = 'Select X-axis';
            app.SelectXaxisDropDown = uidropdown(app.HyProversionUIFigure);
            app.SelectXaxisDropDown.Items = {'%Pt', 'Photocatalyst', 'Methanol', 'pH', 'Irradiation time', 'BET'};
            app.SelectXaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectXaxisDropDownValueChanged, true);
            app.SelectXaxisDropDown.Value = '%Pt';
            app.ShowTrendCheckBox = uicheckbox(app.HyProversionUIFigure);
            app.ShowTrendCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowTrendCheckBoxValueChanged, true);
            app.ShowTrendCheckBox.Text = 'Trend Line';
            app.ShowPointCheckBox = uicheckbox(app.HyProversionUIFigure);
            app.ShowPointCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowPointCheckBoxValueChanged, true);
            app.ShowPointCheckBox.Text = 'Specific Point';
            app.MinEditFieldLabel = uilabel(app.HyProversionUIFigure);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinInput = uieditfield(app.HyProversionUIFigure, 'numeric');
            app.MaxEditFieldLabel = uilabel(app.HyProversionUIFigure);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxInput = uieditfield(app.HyProversionUIFigure, 'numeric');
            app.pHValue = uieditfield(app.HyProversionUIFigure, 'numeric');
            app.pHValue.Limits = [0 14];
            app.H2RangeLabel = uilabel(app.HyProversionUIFigure);
            app.H2RangeLabel.Text = '(Range of prediction...)';
            % Show the figure after all components are created
            app.HyProversionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hydrogen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HyProversionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HyProversionUIFigure)
        end
    end
end